classdef AtlasPainter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AllenRepoEditFieldLabel    matlab.ui.control.Label
        AllenRepoEditField         matlab.ui.control.EditField
        BrowseforAllenRepoButton   matlab.ui.control.Button
        BrowseforSlicesRepoButton  matlab.ui.control.Button
        SlicesRepoEditFieldLabel   matlab.ui.control.Label
        SlicesRepoEditField        matlab.ui.control.EditField
        DorsoVentralSliderLabel    matlab.ui.control.Label
        DorsoVentralSlider         matlab.ui.control.Slider
        LateralSliderLabel         matlab.ui.control.Label
        LateralSlider              matlab.ui.control.Slider
        ZoomknobKnobLabel          matlab.ui.control.Label
        ZoomknobKnob               matlab.ui.control.DiscreteKnob
        SectionKnobLabel           matlab.ui.control.Label
        SectionKnob                matlab.ui.control.Knob
        AtlasKnobLabel             matlab.ui.control.Label
        AtlasKnob                  matlab.ui.control.Knob
        SectionListDropDownLabel   matlab.ui.control.Label
        SectionListDropDown        matlab.ui.control.DropDown
        AreasDropDownLabel         matlab.ui.control.Label
        AreasDropDown              matlab.ui.control.DropDown
        AreaselectionButtonGroup   matlab.ui.container.ButtonGroup
        AllButton                  matlab.ui.control.RadioButton
        VisibleonlyButton          matlab.ui.control.RadioButton
        CustomSelectButton         matlab.ui.control.RadioButton
        DrawModeButtonGroup        matlab.ui.container.ButtonGroup
        PixelPaintButton           matlab.ui.control.ToggleButton
        EllipseButton              matlab.ui.control.ToggleButton
        PolygonButton              matlab.ui.control.ToggleButton
        DrawButton                 matlab.ui.control.StateButton
        LoadButton                 matlab.ui.control.Button
        AreacolorButtonGroup       matlab.ui.container.ButtonGroup
        FastButton                 matlab.ui.control.RadioButton
        AllenButton                matlab.ui.control.RadioButton
        ExtractButtonGroup         matlab.ui.container.ButtonGroup
        CurrentsectionButton       matlab.ui.control.RadioButton
        AllsectionsButton          matlab.ui.control.RadioButton
        ExtractButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        histology_ccf % Description
        histology_ccf_alignment % Description
        curr_slice % Description
        curr_img % Description
        curr_atl % Description
        ax_h % Description
        histology_aligned_av_slices % Description
        curr_atl_RGB % Description
        aligned_RGB % Description
        st % Description
    end
    
    methods (Access = private)
        
        function results = UpdateView(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CreateAxeOnStartup(app)
                app.ax_h = uiaxes('Parent',app.UIFigure,'Position',[350,200,1140,800]);

        end

        % Button pushed function: LoadButton
        function LoadFcn(app, event)
            % Load CCF atlas
%             app.AllenRepoEditField.Value = 'D:\00 Track Location\Allen Brain Mouse\';
%             tv = readNPY([app.AllenRepoEditField.Value filesep 'template_volume_10um.npy']);
%             av = readNPY([app.AllenRepoEditField.Value filesep 'annotation_volume_10um_by_index.npy']);


            % Load corresponding CCF slices
            ccf_slice_fn = [app.SlicesRepoEditField.Value filesep 'histology_ccf.mat'];
            load(ccf_slice_fn);
            app.histology_ccf = histology_ccf;
            app.SectionListDropDown.Items = cellstr(num2str([1:length(histology_ccf)].'));
                        
            
            % Load histology/CCF alignment
            ccf_alignment_fn = [app.SlicesRepoEditField.Value filesep 'atlas2histology_tform.mat'];
            load(ccf_alignment_fn);
            app.histology_ccf_alignment = atlas2histology_tform;
            
            % Remove, default open on GPe example
            app.curr_slice = 17;
            
            app.SectionListDropDown.Value = num2str(app.curr_slice);
            
            app.curr_img = imread(fullfile(app.SlicesRepoEditField.Value, strcat(num2str(app.curr_slice),'.tif')));
            app.curr_atl = app.histology_ccf(app.curr_slice).av_slices;

            tform = affine2d;
            tform.T = app.histology_ccf_alignment{app.curr_slice};   
            tform_size = imref2d([size(app.curr_img,1),size(app.curr_img,2)]);
            
            app.histology_aligned_av_slices{app.curr_slice} = ...
                imwarp(app.curr_atl,tform,'nearest','OutputView',tform_size);
           
            image(app.ax_h,app.curr_img,'AlphaData',app.SectionKnob.Value/100)
            hold(app.ax_h,'on')
            image(app.ax_h,app.histology_aligned_av_slices{app.curr_slice},'AlphaData',app.SectionKnob.Value/100)
            hold(app.ax_h,'off')

            % Area list creation and display
            app.st = loadStructureTree([app.AllenRepoEditField.Value filesep 'structure_tree_safe_2017.csv']);
            AreaSelect = app.AreacolorButtonGroup.SelectedObject;
            
            unique_sphinx_id = unique(app.histology_aligned_av_slices{app.curr_slice});
            unique_sphinx_id = unique_sphinx_id(~isnan(unique_sphinx_id));
            unique_sphinx_id = unique_sphinx_id(unique_sphinx_id>0);
%             st_code_idx = find(app.st.sphinx_id==unique_sphinx_id);
            if strcmp(AreaSelect.Text,'Visible only')
                
                app.AreasDropDown.Items = app.st.safe_name(unique_sphinx_id);
            else
            
                app.AreasDropDown.Items = app.st.safe_name;
            end
            
            
        end

        % Selection changed function: AreacolorButtonGroup
        function ConvertToAllenColors(app, event)
            selectedButton = app.AreacolorButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Allen')
                tform = affine2d;
                tform.T = app.histology_ccf_alignment{app.curr_slice};   
                tform_size = imref2d([size(app.curr_img,1),size(app.curr_img,2)]);
                
                % Function that is retrieving Hexadecimal color code for
                % each pixel, must be improved
                app.curr_atl_RGB{app.curr_slice} = av_to_RGB(app.st, app.curr_atl);
                app.aligned_RGB{app.curr_slice} = ...
                    imwarp(app.curr_atl_RGB{app.curr_slice},tform,'nearest','OutputView',tform_size);
                
                image(app.ax_h,app.curr_img,'AlphaData',app.AtlasKnob.Value/100)
                hold(app.ax_h,'off')
                image(app.ax_h,app.aligned_RGB{app.curr_slice},'AlphaData',app.SectionKnob.Value/100)
                hold(app.ax_h,'on')
                
            elseif strcmp(selectedButton.Text,'Fast')
                
                image(app.ax_h,app.curr_img,'AlphaData',app.SectionKnob.Value/100)
                hold(app.ax_h,'on')
                image(app.ax_h,app.histology_aligned_av_slices{app.curr_slice},'AlphaData',app.SectionKnob.Value/100)
                hold(app.ax_h,'off')
            end
            
        end

        % Button pushed function: ExtractButton
        function ExtractFcn(app, event)
            selectedButton = app.ExtractButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'All sections')

                Side = {'_L','_R'};
                nb_sections = length(app.SectionListDropDown.Items);
                table_headers = {};
                table_headers = {'sphinx_id' 'safe_name' 'Total_L' 'Total_R'};
                for s=1:nb_sections
                    table_headers = [table_headers strcat(num2str(s),Side(1))];
                    table_headers = [table_headers strcat(num2str(s),Side(2))];

                end
%                 TemplateTable = table();
%                 
%                 TemplateTable(1,:) = table(cell(ones(1,length(table_headers))));
%                 TemplateTable.sphinx_id(1:length(app.st.sphinx_id))=app.st.sphinx_id;
%                 TemplateTable.safe_name(1:length(app.st.safe_name))=app.st.safe_name;   
%                 TemplateTable.Properties.VariableNames = table_headers;
%                 TemplateTable(1,:) = num2cell(1:76);
%                 TemplateTable(2,:) = num2cell(1:76);
%                 TemplateTable(2,:) = [];
% 
%                 TemplateTable(1,3:end)=[];
%                

                % Init of results structure
                for c = 1:3
                    for s=  1:nb_sections
                        results.color(c).section(s).nb_pixels = double(app.st.sphinx_id);
                        results.color(c).section(s).mean_fluo = double(app.st.sphinx_id);
                        results.color(c).section(s).median_fluo = double(app.st.sphinx_id);
                        results.color(c).section(s).sum_fluo = double(app.st.sphinx_id);
                        results.color(c).section(s).std_fluo = double(app.st.sphinx_id);
                        results.color(c).section(s).prop_min_fluo = double(app.st.sphinx_id);
                        results.color(c).section(s).prop_max_fluo = double(app.st.sphinx_id);
                        
                        results.color(c).section(s).nb_pixels(1:end,2:3) = nan(size(app.st.sphinx_id,1),2);
                        results.color(c).section(s).mean_fluo(:,2:3) = nan(size(app.st.sphinx_id,1),2);
                        results.color(c).section(s).median_fluo(:,2:3) = nan(size(app.st.sphinx_id,1),2);
                        results.color(c).section(s).sum_fluo(:,2:3) = nan(size(app.st.sphinx_id,1),2);
                        results.color(c).section(s).std_fluo(:,2:3) = nan(size(app.st.sphinx_id,1),2);
                        results.color(c).section(s).prop_min_fluo(:,2:3) = nan(size(app.st.sphinx_id,1),2);
                        results.color(c).section(s).prop_max_fluo(:,2:3) = nan(size(app.st.sphinx_id,1),2);
                    end
                end
                
                
                for s = 1:nb_sections
                    
                    app.curr_img = double(imread(fullfile(app.SlicesRepoEditField.Value, strcat(num2str(s),'.tif'))));
                    app.curr_atl = app.histology_ccf(s).av_slices;
                    
                    tform = affine2d;
                    tform.T = app.histology_ccf_alignment{s};   
                    tform_size = imref2d([size(app.curr_img,1),size(app.curr_img,2)]);
                    
                    app.histology_aligned_av_slices{s} = ...
                    imwarp(app.curr_atl,tform,'nearest','OutputView',tform_size);
            
                    unique_sphinx_id = unique(app.histology_aligned_av_slices{s});
                    unique_sphinx_id = unique_sphinx_id(~isnan(unique_sphinx_id));
                    unique_sphinx_id = unique_sphinx_id(unique_sphinx_id>1);
                    
                    wh = waitbar(0,strcat('Quantif section',num2str(s), ' of ', num2str(nb_sections)));


                    % to implement: thresholded fluo, put
                    % thresholds in main gui
    %                                 results.color(c).section(s).hemi(h).prop_over_thresh_fluo = app.st.sphinx_id;
                    
                    
                    for a = 1:length(unique_sphinx_id)
                        waitbar(a/length(unique_sphinx_id),wh,strcat('Quantif section',num2str(s), ' of ', num2str(nb_sections)));
                     
                        % determine where are the pixels of the area in
                        % question in the atlas and make a mask to
                        % calculate fluo values
                        section_area_mask = [];
                        section_area_mask.logic = app.histology_aligned_av_slices{s} == unique_sphinx_id(a);
                        
                        % Break mask into Left hemisphere (1) and Right hemisphere (2)
                        % (based on half width of centered atlas)

                        section_area_mask.hemi(1).logic=section_area_mask.logic;
                        section_area_mask.hemi(1).logic(:,1:floor(size(app.histology_aligned_av_slices{s},2)/2)) = 0;
                        section_area_mask.hemi(2).logic=section_area_mask.logic;
                        section_area_mask.hemi(2).logic(:,ceil(size(app.histology_aligned_av_slices{s},2):end/2)) = 0;


                        for c=1:3
                            for h = 1:2
                                % Create one mask for each channel, zero-ing
                                % other channels, for each hemisphere
                                section_area_mask.hemi(h).color(c).logic(:,:,1:3) = zeros([size(section_area_mask.hemi(h).logic) 3]);
                                section_area_mask.hemi(h).color(c).logic(:,:,c) = [section_area_mask.hemi(h).logic];
                                section_area_mask.hemi(h).color(c).logic = logical(section_area_mask.hemi(h).color(c).logic);
                        
                                % extract and compute results
                                results.color(c).section(s).nb_pixels(unique_sphinx_id(a),h+1) = sum(sum(sum(section_area_mask.hemi(h).color(c).logic)));
                                results.color(c).section(s).mean_fluo(unique_sphinx_id(a),h+1) = nanmean(app.curr_img(section_area_mask.hemi(h).color(c).logic),'all');
                                results.color(c).section(s).median_fluo(unique_sphinx_id(a),h+1) = nanmedian(app.curr_img(section_area_mask.hemi(h).color(c).logic),'all');
                                results.color(c).section(s).sum_fluo(unique_sphinx_id(a),h+1) = sum(sum(sum(app.curr_img(section_area_mask.hemi(h).color(c).logic))));
                                results.color(c).section(s).std_fluo(unique_sphinx_id(a),h+1) = nanstd(double(app.curr_img(section_area_mask.hemi(h).color(c).logic)),0,'all');
                                
                                results.color(c).section(s).prop_min_fluo(unique_sphinx_id(a),h+1) = numel(find(app.curr_img(section_area_mask.hemi(h).color(c).logic) == 0)) ...
                                    / results.color(c).section(s).nb_pixels(unique_sphinx_id(a),h+1);
                                % Forced to 16bit max (2^16 -1 = 65535)
                                results.color(c).section(s).prop_max_fluo(unique_sphinx_id(a),h+1) = numel(find(app.curr_img(section_area_mask.hemi(h).color(c).logic) == 65535)) ...
                                    / results.color(c).section(s).nb_pixels(unique_sphinx_id(a),h+1);
                                
                                
                            end
                        end
                    % end of area    
                    end
                % end of section    
                end
            else
                % current section only
                
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AllenRepoEditFieldLabel = uilabel(app.UIFigure);
            app.AllenRepoEditFieldLabel.Text = 'Allen Repo';
            app.AllenRepoEditField = uieditfield(app.UIFigure, 'text');
            app.AllenRepoEditField.Value = 'D:\00 Track Location\Allen Brain Mouse';
            app.BrowseforAllenRepoButton = uibutton(app.UIFigure, 'push');
            app.BrowseforAllenRepoButton.Text = 'Browse for Allen Repo';
            app.BrowseforSlicesRepoButton = uibutton(app.UIFigure, 'push');
            app.BrowseforSlicesRepoButton.Text = 'Browse for Slices Repo';
            app.SlicesRepoEditFieldLabel = uilabel(app.UIFigure);
            app.SlicesRepoEditFieldLabel.Text = 'Slices Repo';
            app.SlicesRepoEditField = uieditfield(app.UIFigure, 'text');
            app.SlicesRepoEditField.Value = 'Z:\00 IMAGES\Dave full slides DiD and background only\317\slices';
            app.DorsoVentralSliderLabel = uilabel(app.UIFigure);
            app.DorsoVentralSliderLabel.Text = 'Dorso / Ventral';
            app.DorsoVentralSlider = uislider(app.UIFigure);
            app.LateralSliderLabel = uilabel(app.UIFigure);
            app.LateralSliderLabel.Text = 'Lateral';
            app.LateralSlider = uislider(app.UIFigure);
            app.ZoomknobKnobLabel = uilabel(app.UIFigure);
            app.ZoomknobKnobLabel.Text = 'Zoom knob';
            app.ZoomknobKnob = uiknob(app.UIFigure, 'discrete');
            app.SectionKnobLabel = uilabel(app.UIFigure);
            app.SectionKnobLabel.Text = 'Section';
            app.SectionKnob = uiknob(app.UIFigure, 'continuous');
            app.SectionKnob.Value = 50;
            app.AtlasKnobLabel = uilabel(app.UIFigure);
            app.AtlasKnobLabel.Text = 'Atlas';
            app.AtlasKnob = uiknob(app.UIFigure, 'continuous');
            app.AtlasKnob.Value = 30;
            app.SectionListDropDownLabel = uilabel(app.UIFigure);
            app.SectionListDropDownLabel.Text = 'Section List';
            app.SectionListDropDown = uidropdown(app.UIFigure);
            app.AreasDropDownLabel = uilabel(app.UIFigure);
            app.AreasDropDownLabel.Text = 'Areas';
            app.AreasDropDown = uidropdown(app.UIFigure);
            app.AreaselectionButtonGroup = uibuttongroup(app.UIFigure);
            app.AreaselectionButtonGroup.Title = 'Area selection';
            app.AllButton = uiradiobutton(app.AreaselectionButtonGroup);
            app.AllButton.Text = 'All';
            app.VisibleonlyButton = uiradiobutton(app.AreaselectionButtonGroup);
            app.VisibleonlyButton.Text = 'Visible only';
            app.VisibleonlyButton.Value = true;
            app.CustomSelectButton = uiradiobutton(app.AreaselectionButtonGroup);
            app.CustomSelectButton.Text = 'Custom Select';
            app.DrawModeButtonGroup = uibuttongroup(app.UIFigure);
            app.DrawModeButtonGroup.Title = 'Draw Mode';
            app.PixelPaintButton = uitogglebutton(app.DrawModeButtonGroup);
            app.PixelPaintButton.Text = 'Pixel Paint';
            app.PixelPaintButton.Value = true;
            app.EllipseButton = uitogglebutton(app.DrawModeButtonGroup);
            app.EllipseButton.Text = 'Ellipse';
            app.PolygonButton = uitogglebutton(app.DrawModeButtonGroup);
            app.PolygonButton.Text = 'Polygon';
            app.DrawButton = uibutton(app.UIFigure, 'state');
            app.DrawButton.Text = 'Draw';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFcn, true);
            app.LoadButton.Text = 'Load';
            app.AreacolorButtonGroup = uibuttongroup(app.UIFigure);
            app.AreacolorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConvertToAllenColors, true);
            app.AreacolorButtonGroup.Title = 'Area color';
            app.FastButton = uiradiobutton(app.AreacolorButtonGroup);
            app.FastButton.Text = 'Fast';
            app.FastButton.Value = true;
            app.AllenButton = uiradiobutton(app.AreacolorButtonGroup);
            app.AllenButton.Text = 'Allen';
            app.ExtractButtonGroup = uibuttongroup(app.UIFigure);
            app.ExtractButtonGroup.Title = 'Extract';
            app.CurrentsectionButton = uiradiobutton(app.ExtractButtonGroup);
            app.CurrentsectionButton.Text = 'Current section';
            app.CurrentsectionButton.Value = true;
            app.AllsectionsButton = uiradiobutton(app.ExtractButtonGroup);
            app.AllsectionsButton.Text = 'All sections';
            app.ExtractButton = uibutton(app.ExtractButtonGroup, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractFcn, true);
            app.ExtractButton.Text = 'Extract';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AtlasPainter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @CreateAxeOnStartup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end